classdef App_Open_Ephys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenExternalButton              matlab.ui.control.Button
        HoldonCheckBox                  matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        OpenFileTab                     matlab.ui.container.Tab
        DateandTimeEditField            matlab.ui.control.EditField
        DateandTimeEditFieldLabel       matlab.ui.control.Label
        DataTypeButtonGroup             matlab.ui.container.ButtonGroup
        Normalized11Button              matlab.ui.control.RadioButton
        uVButton                        matlab.ui.control.RadioButton
        mVButton                        matlab.ui.control.RadioButton
        UnprocessedButton               matlab.ui.control.RadioButton
        TextArea_2                      matlab.ui.control.TextArea
        DisplayTTLButton                matlab.ui.control.Button
        ChannelEditField                matlab.ui.control.NumericEditField
        ChannelEditFieldLabel           matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        PlotTab                         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        LimitsTab                       matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        RelativeTab                     matlab.ui.container.Tab
        RelativePositionSlider          matlab.ui.control.Slider
        RelativePositionSliderLabel     matlab.ui.control.Label
        SelectButton                    matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        TimesEditField                  matlab.ui.control.NumericEditField
        TimesEditFieldLabel             matlab.ui.control.Label
        AbsolutTab                      matlab.ui.container.Tab
        YLimitHigherEditField           matlab.ui.control.NumericEditField
        YLimitHigherEditFieldLabel      matlab.ui.control.Label
        XLimitHigherEditField           matlab.ui.control.NumericEditField
        XLimitHigherEditFieldLabel      matlab.ui.control.Label
        YLimitLowerEditField            matlab.ui.control.NumericEditField
        YLimitLowerEditFieldLabel       matlab.ui.control.Label
        XLimitLowerEditField            matlab.ui.control.NumericEditField
        XLimitLowerEditFieldLabel       matlab.ui.control.Label
        ApplyLimitsButton               matlab.ui.control.Button
        LabelsTab                       matlab.ui.container.Tab
        GridyonCheckBox                 matlab.ui.control.CheckBox
        GridxonCheckBox                 matlab.ui.control.CheckBox
        ApplyChangesButton              matlab.ui.control.Button
        YLabelEditField                 matlab.ui.control.EditField
        YLabelEditFieldLabel            matlab.ui.control.Label
        XLabelEditField                 matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        PlotTitleEditField              matlab.ui.control.EditField
        PlotTitleEditFieldLabel         matlab.ui.control.Label
        DataColorTab                    matlab.ui.container.Tab
        ApplyToButtonGroup              matlab.ui.container.ButtonGroup
        IndexSpinner                    matlab.ui.control.Spinner
        IndexSpinnerLabel               matlab.ui.control.Label
        IndexButton                     matlab.ui.control.RadioButton
        LastButton                      matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        OriginalButton                  matlab.ui.control.Button
        ApplyColourButton               matlab.ui.control.Button
        LineWidthEditField              matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        BlueSlider                      matlab.ui.control.Slider
        BlueSliderLabel                 matlab.ui.control.Label
        GreenSlider                     matlab.ui.control.Slider
        GreenSliderLabel                matlab.ui.control.Label
        RedSlider                       matlab.ui.control.Slider
        RedSliderLabel                  matlab.ui.control.Label
        LegendTab                       matlab.ui.container.Tab
        NOTWORKINGPROPERLYYETLabel      matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        ApplyLegendButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        FilteringTab                    matlab.ui.container.Tab
        FFTAnalysisButton               matlab.ui.control.Button
        SavitzkyGolayFilteringPanel     matlab.ui.container.Panel
        ActiveCheckBox_4                matlab.ui.control.CheckBox
        FrameLengthoddEditField         matlab.ui.control.NumericEditField
        FrameLengthoddEditFieldLabel    matlab.ui.control.Label
        OrderEditField_2                matlab.ui.control.NumericEditField
        OrderEditField_2Label           matlab.ui.control.Label
        HighpassFilterPanel             matlab.ui.container.Panel
        ActiveCheckBox_3                matlab.ui.control.CheckBox
        CutoffFrequencyEditField_2      matlab.ui.control.NumericEditField
        CutoffFrequencyEditField_2Label  matlab.ui.control.Label
        GeneratePeriodogramButton       matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        LowpassFilterPanel              matlab.ui.container.Panel
        ActiveCheckBox_2                matlab.ui.control.CheckBox
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        CutoffFrequencyEditFieldLabel   matlab.ui.control.Label
        HarmonicFilterPanel             matlab.ui.container.Panel
        AttenuationdBEditField          matlab.ui.control.NumericEditField
        AttenuationdBEditFieldLabel     matlab.ui.control.Label
        QualityEditField                matlab.ui.control.NumericEditField
        QualityEditFieldLabel           matlab.ui.control.Label
        ActiveCheckBox                  matlab.ui.control.CheckBox
        NaturalFrequencyEditField       matlab.ui.control.NumericEditField
        NaturalFrequencyEditFieldLabel  matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        PlotMultipleTab                 matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        MainTab_2                       matlab.ui.container.Tab
        FilterCheckBox                  matlab.ui.control.CheckBox
        StyleButtonGroup                matlab.ui.container.ButtonGroup
        nx2Button                       matlab.ui.control.RadioButton
        nx3Button                       matlab.ui.control.RadioButton
        xnButton_2                      matlab.ui.control.RadioButton
        xnButton                        matlab.ui.control.RadioButton
        HorizontalButton                matlab.ui.control.RadioButton
        VerticalButton                  matlab.ui.control.RadioButton
        FlowButton                      matlab.ui.control.RadioButton
        ChannelsToPlot                  matlab.ui.control.EditField
        ChannelsEditField_2Label        matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        LimitsTab_2                     matlab.ui.container.Tab
        YLimitHigherEditField_2         matlab.ui.control.NumericEditField
        YLimitHigherEditField_2Label    matlab.ui.control.Label
        XLimitHigherEditField_2         matlab.ui.control.NumericEditField
        XLimitHigherEditField_2Label    matlab.ui.control.Label
        YLimitLowerEditField_2          matlab.ui.control.NumericEditField
        YLimitLowerEditField_2Label     matlab.ui.control.Label
        XLimitLowerEditField_2          matlab.ui.control.NumericEditField
        XLimitLowerEditField_2Label     matlab.ui.control.Label
        ApplyLimitsButton_2             matlab.ui.control.Button
        MathTab                         matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        OperationButtonGroup            matlab.ui.container.ButtonGroup
        Integral1Button                 matlab.ui.control.RadioButton
        Derivative1Button               matlab.ui.control.RadioButton
        Subtraction12Button             matlab.ui.control.RadioButton
        Channel2EditField               matlab.ui.control.NumericEditField
        Channel2EditFieldLabel          matlab.ui.control.Label
        Channel1EditField               matlab.ui.control.NumericEditField
        Channel1EditFieldLabel          matlab.ui.control.Label
        ExportTab                       matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        SelectDirectoryButton           matlab.ui.control.Button
        FileNameEditField_2             matlab.ui.control.EditField
        FileNameEditField_2Label        matlab.ui.control.Label
        ChannelsEditField               matlab.ui.control.EditField
        ChannelsEditFieldLabel          matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        FilteredButtonGroup             matlab.ui.container.ButtonGroup
        FilteredDataButton              matlab.ui.control.RadioButton
        RawDataButton                   matlab.ui.control.RadioButton
        TextArea                        matlab.ui.control.TextArea
        AdditionalInfoTab               matlab.ui.container.Tab
        MassEditField                   matlab.ui.control.EditField
        MassEditFieldLabel              matlab.ui.control.Label
        AnimalEditField                 matlab.ui.control.EditField
        AnimalEditFieldLabel            matlab.ui.control.Label
        OBSTextArea                     matlab.ui.control.TextArea
        OBSTextAreaLabel                matlab.ui.control.Label
        RecordingEditField              matlab.ui.control.EditField
        RecordingEditFieldLabel         matlab.ui.control.Label
        DateEditField                   matlab.ui.control.EditField
        DateEditFieldLabel              matlab.ui.control.Label
        HelpTab_2                       matlab.ui.container.Tab
        HowtoReadExportedDataTextArea   matlab.ui.control.TextArea
        HowtoReadExportedDataTextAreaLabel  matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        TextArea_3                      matlab.ui.control.TextArea
        MEAElectrodeDispositionButton   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OData % Keep Original Data
        D % Continous imported data
        E % Event data (TTL)
        data % Channel data
        Fs % Sample rate
        fData % Filtered data
        channels % Channels to export     
        ExpPath = ''; % ExportPathName
        axes = [] % Multiple Plot Axes
        bitVolts = 1 % Ajust parameters according to Open Ephys Documentation
        newcolor = "#0072BD" %plot color
        temptable = readtable('Teste.xlsx', 'Sheet',1) %temporary table
        linesize = 0;        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.oebin');
            fullfilename = fullfile(pathname,filename);

            app.D = load_open_ephys_binary(fullfilename, 'continuous', 1); % Load the data file
            app.OData = app.D.Data*app.bitVolts;
            app.E = load_open_ephys_binary(fullfilename, 'events', 1); % Load the TTL file
            app.Fs = app.D.Header.sample_rate; % Sample rate

            app.PathEditField.Value = pathname;

            time = CalcTime(pathname);
            app.DateandTimeEditField.Value = string(time);
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            
            switch app.DataTypeButtonGroup.SelectedObject.Text
                case 'Unprocessed'
                    app.bitVolts = 1;
                case 'mV'
                    app.bitVolts = 0.1949999928474426/1000;
                case 'uV'
                    app.bitVolts = 0.1949999928474426;                
            end

            app.OData = app.D.Data*app.bitVolts; % Data converted.
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            channel = app.ChannelEditField.Value;          
            %% Convert data to selected type
            TempData = app.OData(channel, :);
            if isequal(app.DataTypeButtonGroup.SelectedObject.Text, 'Normalized [-1,1]')                
                app.data = 2*((TempData - min(TempData))/ (max(TempData) - min(TempData)))-1;
            else
                app.data = TempData*app.bitVolts; % Data converted.
            end
            plot(app.UIAxes, app.D.Timestamps, app.data);
        end

        % Button pushed function: DisplayTTLButton
        function DisplayTTLButtonPushed(app, event)
            hold(app.UIAxes, "on");
            gridxy(app.UIAxes, app.E.Timestamps(1:2:end), 'Color', 'r');
            hold(app.UIAxes, "off");
        end

        % Button pushed function: OpenExternalButton
        function OpenExternalButtonPushed(app, event)
            % Create new figure and new axes
            f = figure;
            f.Position = [100 100 750 400]; 
            axNew = axes; 
            % Copy all opjects from UIAxes to new axis
            copyobj(app.UIAxes.Children, axNew)
            % Save all parameters of the UIAxes
            uiAxParams = get(app.UIAxes);
            uiAxParamNames = fieldnames(uiAxParams); 
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axNew)); 
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition']; 
            uiAxGoodParams = rmfield(uiAxParams,badFields); 
            % set editable params on new axes
            set(axNew, uiAxGoodParams)
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed2(app, event)
          
            har_Q = app.QualityEditField.Value;
            har_freq = app.NaturalFrequencyEditField.Value;
            har_order = app.OrderEditField.Value;
            har_ast = app.AttenuationdBEditField.Value;
            hp_freq = app.CutoffFrequencyEditField_2.Value;
            lp_freq = app.CutoffFrequencyEditField.Value;
            sg_order = app.OrderEditField_2.Value;
            sg_framelen = app.FrameLengthoddEditField.Value;

            checkboxValue1_4 = [app.ActiveCheckBox.Value
                app.ActiveCheckBox_2.Value
                app.ActiveCheckBox_3.Value
                app.ActiveCheckBox_4.Value];

            app.fData = filterdata(app.data, checkboxValue1_4, har_Q, har_freq, har_order,...
                har_ast, hp_freq, lp_freq, sg_order, sg_framelen, app.Fs);

            plot(app.UIAxes, app.D.Timestamps, app.fData);
        end

        % Button pushed function: GeneratePeriodogramButton
        function GeneratePeriodogramButtonPushed(app, event)
            if isequal(app.fData, [])
                error('No data to compute.');
                return
            end
            [pdata,fsdata]=periodogram(app.data,[],length(app.data),app.Fs);
            [pFData,fFData]=periodogram(app.fData, [], length(app.fData), app.Fs);
            figure;
            hold on;
            tiledlayout(2, 1);
            ax1 = nexttile;
            plot(fsdata,10*log10(abs(pdata)), 'r' );
            title('Unfiltered data');
            ylabel('dB')

            ax2 = nexttile;
            plot(fFData,10*log10(abs(pFData)),'b');
            title('Filtered data');
            xlabel('Frequency (Hz)');
            ylabel('dB')

            linkaxes([ax1 ax2], 'xy');
        end

        % Button pushed function: FFTAnalysisButton
        function FFTAnalysisButtonPushed(app, event)
            if isequal(app.fData, [])
                error('No data to compute.');
                return
            end
            
            % Raw data
            rData = fftshift(app.data);
            rn = length(app.data);          % number of samples
            rf = (-rn/2:rn/2-1)*(app.Fs/rn);     % frequency range
            rPower = abs(rData).^2/rn;    % power of the DFT

            % Filtered Data
            filData = fftshift(app.fData);
            fn = length(app.fData);          % number of samples
            ff = (-fn/2:fn/2-1)*(app.Fs/fn);     % frequency range
            filPower = abs(filData).^2/fn;    % power of the DFT
            
            figure;
            hold on;
            tiledlayout(2, 1);
            ax1 = nexttile;
            plot(rf,rPower, 'Color', 'r');
            title('Unfiltered data');
            ylabel('Power');

            ax2 = nexttile;
            plot(ff,filPower, 'Color', 'b')
            title('Filtered data');
            xlabel('Frequency');
            ylabel('Power');

            linkaxes([ax1 ax2], 'xy');
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            ax = app.UIAxes;
            title(ax, app.PlotTitleEditField.Value);
            xlabel(ax, app.XLabelEditField.Value, 'Interpreter','latex');
            ylabel(ax, app.YLabelEditField.Value, 'Interpreter','latex');

            if app.GridxonCheckBox.Value == 1
                ax.XGrid = 'on';
            else
                ax.XGrid = 'off';
            end

            if app.GridyonCheckBox.Value == 1
                ax.YGrid = 'on';
            else
                ax.YGrid = 'off';
            end
        end

        % Button pushed function: ApplyLimitsButton
        function ApplyLimitsButtonPushed(app, event)
            app.UIAxes.XLim = [app.XLimitLowerEditField.Value app.XLimitHigherEditField.Value];
            app.UIAxes.YLim = [app.YLimitLowerEditField.Value app.YLimitHigherEditField.Value];
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            XLimInf = floor(app.D.Timestamps(ceil(end*app.RelativePositionSlider.Value/100)));
            XLimSup = XLimInf + app.TimesEditField.Value;

            [~, idxInf] = min(abs(app.D.Timestamps - XLimInf));
            [~, idxSup] = min(abs(app.D.Timestamps - XLimSup));

            a = min(app.data(idxInf:idxSup));
            b = max(app.data(idxInf:idxSup));
            
            YLimInf = a - abs(a*0.1);
            YLimSup = b + b*0.1;

            app.UIAxes.XLim = [XLimInf XLimSup];
            app.UIAxes.YLim = [YLimInf YLimSup];            
        end

        % Value changing function: RelativePositionSlider
        function RelativePositionSliderValueChanging(app, event)
            changingValue = event.Value;
            XLimInf = app.D.Timestamps(ceil(end*(changingValue*0.999)/100));
            XLimSup = XLimInf + app.TimesEditField.Value;

            app.UIAxes.XLim = [XLimInf XLimSup];
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            app.Lamp.Color = [app.RedSlider.Value, app.GreenSlider.Value, app.BlueSlider.Value]/255;
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            app.Lamp.Color = [app.RedSlider.Value, app.GreenSlider.Value, app.BlueSlider.Value]/255;
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            app.Lamp.Color = [app.RedSlider.Value, app.GreenSlider.Value, app.BlueSlider.Value]/255;
        end

        % Button pushed function: ApplyColourButton
        function ApplyColourButtonPushed(app, event)
            ax = app.UIAxes;
            lines = findobj(ax,'Type','Line');
            app.newcolor = [app.RedSlider.Value, app.GreenSlider.Value, app.BlueSlider.Value]/255;
            
            if app.LastButton.Value == 1
                lines(1).LineWidth = app.LineWidthEditField.Value;
                lines(1).Color = app.newcolor;
            elseif app.AllButton.Value == 1
                for i = 1:length(lines)
                    lines(i).LineWidth = app.LineWidthEditField.Value;
                    lines(i).Color = app.newcolor;
                end 
            elseif app.IndexButton.Value == 1
                lines(end-app.IndexSpinner.Value+1).LineWidth = app.LineWidthEditField.Value;
                lines(end-app.IndexSpinner.Value+1).Color = app.newcolor;
            end  
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            ax = app.UIAxes;
            lines = findobj(ax, 'Type', 'Line'); 

            if length(lines) < app.linesize
                app.temptable = readtable('Teste.xlsx','Sheet',1);
            end
            
            app.UITable.Data = app.temptable;
            app.linesize = length(lines); 

            for k = 1:length(lines)
                app.UITable.Data.Index(k) = k;
                if isempty(app.UITable.Data.Legend{k})
                    app.UITable.Data.Legend{k} = 'none';
                end
            end
            return
        end

        % Button pushed function: ApplyLegendButton
        function ApplyLegendButtonPushed(app, event)
            ax = app.UIAxes;
            
            legend(ax, app.UITable.Data.Legend);
            app.temptable = app.UITable.Data;            
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            ax = app.UIAxes;
            oldcolors = ["#0072BD" "#D95319" "#EDB120" "#7E2F8E" "#77AC30" "#4DBEEE" "#A2142F"];
            colororder(ax, oldcolors);

            lines = findobj(ax,'Type','Line');
            for i = 0:length(lines)
                lines(i+1).LineWidth = app.LineWidthEditField.Value; 
                lines(end-i).Color = oldcolors(mod(i, 7)+1);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.axes = [];
            PlotChannels = str2num(app.ChannelsToPlot.Value); 
            tempdata = app.OData(PlotChannels, :);

            if app.FilterCheckBox.Value == 1
                har_Q = app.QualityEditField.Value;
                har_freq = app.NaturalFrequencyEditField.Value;
                har_order = app.OrderEditField.Value;
                hp_freq = app.CutoffFrequencyEditField_2.Value;
                lp_freq = app.CutoffFrequencyEditField.Value;
                sg_order = app.OrderEditField_2.Value;
                sg_framelen = app.FrameLengthoddEditField.Value;
                hp_ast = app.AttenuationdBEditField.Value;
    
                checkboxValue1_4 = [app.ActiveCheckBox.Value
                    app.ActiveCheckBox_2.Value
                    app.ActiveCheckBox_3.Value
                    app.ActiveCheckBox_4.Value];

                for i = 1:length(PlotChannels)
                    tempdata(i, :) = filterdata(tempdata(i, :), checkboxValue1_4, har_Q, har_freq, har_order,...
                        hp_ast, hp_freq, lp_freq, sg_order, sg_framelen, app.Fs);
                end
            end
            if ~isempty(findobj('type','figure','name','MPlot'))
                close 'MPlot'
            end

            figure('Name', 'MPlot');

            switch app.StyleButtonGroup.SelectedObject.Text
                case 'Flow'
                    tiledlayout('flow');
                case 'Vertical'
                    tiledlayout(length(PlotChannels), 1);
                case 'Horizontal'
                    tiledlayout(1, length(PlotChannels));
                case '2 x n'
                    c = ceil(length(PlotChannels)/2);
                    tiledlayout(2, c);
                case '3 x n'
                    c = ceil(length(PlotChannels)/3);
                    tiledlayout(3, c);
                case 'n x 2'
                    c = ceil(length(PlotChannels)/3);
                    tiledlayout(c, 2);
                case 'n x 3'  
                    c = ceil(length(PlotChannels)/3);
                    tiledlayout(c, 3);
            end            

            for i = 1:length(PlotChannels)
                ax = nexttile;
                plot(app.D.Timestamps, tempdata(i, :), 'Color', app.newcolor);
                title(['Channel ' num2str(PlotChannels(i))]);

                app.axes = cat(2, app.axes, ax);
            end

            linkaxes(app.axes, 'xy')
            app.axes(1).XLim = app.UIAxes.XLim;
            app.axes(1).YLim = app.UIAxes.YLim;
        
        end

        % Button pushed function: ApplyLimitsButton_2
        function ApplyLimitsButton_2Pushed(app, event)
            app.axes(1).XLim = [app.XLimitLowerEditField_2.Value app.XLimitHigherEditField_2.Value];
            app.axes(1).YLim = [app.YLimitLowerEditField_2.Value app.YLimitHigherEditField_2.Value];
        end

        % Button pushed function: MEAElectrodeDispositionButton
        function MEAElectrodeDispositionButtonPushed(app, event)
            Atrial = rescale(imread('AtrialMEA.JPG'), 'InputMin',0,'InputMax', 255);
            Ventricular = rescale(imread('VentricularMEA.JPG'), 'InputMin',0,'InputMax', 255);
            
            montage([Atrial(:, :, 1) Ventricular(:, :, 1)])
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            app.ExpPath = uigetdir();            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.channels = str2num(app.ChannelsEditField.Value); 
            FileName = fullfile(app.ExpPath, strcat(app.FileNameEditField_2.Value, '.mat'));

            if app.RawDataButton.Value == 1
                expDataRaw.Header.Channels = app.channels;
                expDataRaw.Header.Fs = app.Fs;
                expDataRaw.Header.Recording = app.RecordingEditField.Value;
                expDataRaw.Header.Date = app.DateEditField.Value;
                expDataRaw.Header.OBS = app.OBSTextArea.Value;
                expDataRaw.Header.Animal = app.AnimalEditField.Value;
                expDataRaw.Header.Mass = app.MassEditField.Value;

                expDataRaw.Data = app.OData(app.channels, :);
                expDataRaw.Timestamps = app.D.Timestamps;
                expDataRaw.TTL = app.E.Timestamps(1:2:end);
                
                save(FileName, '-struct', 'expDataRaw');

            elseif app.FilteredDataButton.Value == 1 
                expDataFil.Header.Channels = app.channels;
                expDataFil.Header.Fs = app.Fs;
                expDataFil.Header.Recording = app.RecordingEditField.Value;
                expDataFil.Header.Date = app.DateEditField.Value;
                expDataFil.Header.OBS = app.OBSTextArea.Value;
                expDataFil.Header.Animal = app.AnimalEditField.Value;
                expDataFil.Header.Mass = app.MassEditField.Value;

                expDataFil.Data = app.OData(app.channels, :);
                expDataFil.Timestamps = app.D.Timestamps;
                expDataFil.TTL = app.E.Timestamps(1:2:end);

                har_Q = app.QualityEditField.Value;
                har_freq = app.NaturalFrequencyEditField.Value;
                har_order = app.OrderEditField.Value;
                hp_freq = app.CutoffFrequencyEditField_2.Value;
                lp_freq = app.CutoffFrequencyEditField.Value;
                sg_order = app.OrderEditField_2.Value;
                sg_framelen = app.FrameLengthoddEditField.Value;
    
                checkboxValue1_4 = [app.ActiveCheckBox.Value
                    app.ActiveCheckBox_2.Value
                    app.ActiveCheckBox_3.Value
                    app.ActiveCheckBox_4.Value];               

                for i = 1:length(app.channels)
                    expDataFil.Data(i, :) = filterdata(expDataFil.Data(i, :), checkboxValue1_4, har_Q, har_freq, har_order,...
                        hp_freq, lp_freq, sg_order, sg_framelen, app.Fs);
                end

                save(FileName, '-struct', 'expDataFil'); 
            end                           
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            channel1 = app.Channel1EditField.Value;
            channel2 = app.Channel2EditField.Value;

                 
            switch app.OperationButtonGroup.SelectedObject.Text
                case 'Subtraction (1 -2)'
                    app.data = app.OData(channel1, :) - app.OData(channel2, :);
                    plot(app.UIAxes, app.D.Timestamps, app.data);
                case 'Derivative (1)'
                    app.data = diff(app.OData(channel1, :))./diff(app.D.Timestamps)';
                    app.data = cat(2, app.data, app.data(end));
                    plot(app.UIAxes, app.D.Timestamps, app.data);
                case 'Integral (1)'
            end            
            
        end

        % Value changed function: HoldonCheckBox
        function HoldonCheckBoxValueChanged(app, event)
            value = app.HoldonCheckBox.Value;
            ax = app.UIAxes;
            if value == 1                
                hold(ax, "on");
            else
                hold(ax, 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OpenFileTab = uitab(app.TabGroup);
            app.OpenFileTab.Title = 'Open File';
            app.SelectFileButton = uibutton(app.OpenFileTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.RunButton = uibutton(app.OpenFileTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PathEditFieldLabel = uilabel(app.OpenFileTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.OpenFileTab, 'text');
            app.ChannelEditFieldLabel = uilabel(app.OpenFileTab);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.OpenFileTab, 'numeric');
            app.ChannelEditField.Value = 10;
            app.DisplayTTLButton = uibutton(app.OpenFileTab, 'push');
            app.DisplayTTLButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayTTLButtonPushed, true);
            app.DisplayTTLButton.Text = 'Display TTL';
            app.TextArea_2 = uitextarea(app.OpenFileTab);
            app.TextArea_2.Value = {'Select a ''structure.oebin'' in a ''recordingN'' folder. Choose the desired channel to visualize.'};
            app.DataTypeButtonGroup = uibuttongroup(app.OpenFileTab);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.UnprocessedButton = uiradiobutton(app.DataTypeButtonGroup);
            app.UnprocessedButton.Text = 'Unprocessed';
            app.UnprocessedButton.Value = true;
            app.mVButton = uiradiobutton(app.DataTypeButtonGroup);
            app.mVButton.Text = 'mV';
            app.uVButton = uiradiobutton(app.DataTypeButtonGroup);
            app.uVButton.Text = 'uV';
            app.Normalized11Button = uiradiobutton(app.DataTypeButtonGroup);
            app.Normalized11Button.Text = 'Normalized [-1,1]';
            app.DateandTimeEditFieldLabel = uilabel(app.OpenFileTab);
            app.DateandTimeEditFieldLabel.Text = 'Date and Time';
            app.DateandTimeEditField = uieditfield(app.OpenFileTab, 'text');
            app.DateandTimeEditField.Editable = 'off';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.TabGroup2 = uitabgroup(app.PlotTab);
            app.LimitsTab = uitab(app.TabGroup2);
            app.LimitsTab.Title = 'Limits';
            app.TabGroup5 = uitabgroup(app.LimitsTab);
            app.RelativeTab = uitab(app.TabGroup5);
            app.RelativeTab.Title = 'Relative';
            app.TimesEditFieldLabel = uilabel(app.RelativeTab);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.RelativeTab, 'numeric');
            app.TimesEditField.Value = 1;
            app.TextArea_5 = uitextarea(app.RelativeTab);
            app.TextArea_5.Value = {'Select a period of time from the center of the signal.'};
            app.SelectButton = uibutton(app.RelativeTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.RelativePositionSliderLabel = uilabel(app.RelativeTab);
            app.RelativePositionSliderLabel.Text = 'Relative Position';
            app.RelativePositionSlider = uislider(app.RelativeTab);
            app.RelativePositionSlider.Limits = [0.1 100];
            app.RelativePositionSlider.MajorTicks = [0.1 10 20 30 40 50 60 70 80 90 100];
            app.RelativePositionSlider.ValueChangingFcn = createCallbackFcn(app, @RelativePositionSliderValueChanging, true);
            app.RelativePositionSlider.Value = 50;
            app.AbsolutTab = uitab(app.TabGroup5);
            app.AbsolutTab.Title = 'Absolut';
            app.ApplyLimitsButton = uibutton(app.AbsolutTab, 'push');
            app.ApplyLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLimitsButtonPushed, true);
            app.ApplyLimitsButton.Text = 'Apply Limits';
            app.XLimitLowerEditFieldLabel = uilabel(app.AbsolutTab);
            app.XLimitLowerEditFieldLabel.Text = 'X Limit Lower';
            app.XLimitLowerEditField = uieditfield(app.AbsolutTab, 'numeric');
            app.YLimitLowerEditFieldLabel = uilabel(app.AbsolutTab);
            app.YLimitLowerEditFieldLabel.Text = 'Y Limit Lower';
            app.YLimitLowerEditField = uieditfield(app.AbsolutTab, 'numeric');
            app.XLimitHigherEditFieldLabel = uilabel(app.AbsolutTab);
            app.XLimitHigherEditFieldLabel.Text = 'X Limit Higher';
            app.XLimitHigherEditField = uieditfield(app.AbsolutTab, 'numeric');
            app.XLimitHigherEditField.Value = 1;
            app.YLimitHigherEditFieldLabel = uilabel(app.AbsolutTab);
            app.YLimitHigherEditFieldLabel.Text = 'Y Limit Higher';
            app.YLimitHigherEditField = uieditfield(app.AbsolutTab, 'numeric');
            app.YLimitHigherEditField.Value = 1;
            app.LabelsTab = uitab(app.TabGroup2);
            app.LabelsTab.Title = 'Labels';
            app.PlotTitleEditFieldLabel = uilabel(app.LabelsTab);
            app.PlotTitleEditFieldLabel.Text = 'Plot Title';
            app.PlotTitleEditField = uieditfield(app.LabelsTab, 'text');
            app.PlotTitleEditField.Value = 'Experiment N, Recording N, Channel N';
            app.XLabelEditFieldLabel = uilabel(app.LabelsTab);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabelEditField = uieditfield(app.LabelsTab, 'text');
            app.XLabelEditField.Value = 'Time [s]';
            app.YLabelEditFieldLabel = uilabel(app.LabelsTab);
            app.YLabelEditFieldLabel.Text = 'Y Label';
            app.YLabelEditField = uieditfield(app.LabelsTab, 'text');
            app.YLabelEditField.Value = 'Amplitude [$\mu$V]';
            app.ApplyChangesButton = uibutton(app.LabelsTab, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.GridxonCheckBox = uicheckbox(app.LabelsTab);
            app.GridxonCheckBox.Text = 'Grid x on';
            app.GridyonCheckBox = uicheckbox(app.LabelsTab);
            app.GridyonCheckBox.Text = 'Grid y on';
            app.DataColorTab = uitab(app.TabGroup2);
            app.DataColorTab.Title = 'Data Color';
            app.RedSliderLabel = uilabel(app.DataColorTab);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.DataColorTab);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.DataColorTab);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.DataColorTab);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.DataColorTab);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.DataColorTab);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.Lamp = uilamp(app.DataColorTab);
            app.LineWidthEditFieldLabel = uilabel(app.DataColorTab);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.LineWidthEditField = uieditfield(app.DataColorTab, 'numeric');
            app.LineWidthEditField.Value = 1;
            app.ApplyColourButton = uibutton(app.DataColorTab, 'push');
            app.ApplyColourButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyColourButtonPushed, true);
            app.ApplyColourButton.Text = 'Apply Colour';
            app.OriginalButton = uibutton(app.DataColorTab, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Text = 'Original';
            app.ApplyToButtonGroup = uibuttongroup(app.DataColorTab);
            app.ApplyToButtonGroup.Title = 'Apply To';
            app.AllButton = uiradiobutton(app.ApplyToButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.LastButton = uiradiobutton(app.ApplyToButtonGroup);
            app.LastButton.Text = 'Last';
            app.IndexButton = uiradiobutton(app.ApplyToButtonGroup);
            app.IndexButton.Text = 'Index';
            app.IndexSpinnerLabel = uilabel(app.ApplyToButtonGroup);
            app.IndexSpinnerLabel.Text = 'Index';
            app.IndexSpinner = uispinner(app.ApplyToButtonGroup);
            app.IndexSpinner.Limits = [1 20];
            app.IndexSpinner.Value = 1;
            app.LegendTab = uitab(app.TabGroup2);
            app.LegendTab.Title = 'Legend';
            app.UITable = uitable(app.LegendTab);
            app.UITable.ColumnName = {'Index'; 'Legend'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.ApplyLegendButton = uibutton(app.LegendTab, 'push');
            app.ApplyLegendButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLegendButtonPushed, true);
            app.ApplyLegendButton.Text = 'Apply Legend';
            app.RefreshButton = uibutton(app.LegendTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.NOTWORKINGPROPERLYYETLabel = uilabel(app.LegendTab);
            app.NOTWORKINGPROPERLYYETLabel.Text = 'NOT WORKING PROPERLY YET';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.HarmonicFilterPanel = uipanel(app.FilteringTab);
            app.HarmonicFilterPanel.Title = 'Harmonic Filter';
            app.OrderEditFieldLabel = uilabel(app.HarmonicFilterPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.HarmonicFilterPanel, 'numeric');
            app.OrderEditField.Value = 6;
            app.NaturalFrequencyEditFieldLabel = uilabel(app.HarmonicFilterPanel);
            app.NaturalFrequencyEditFieldLabel.Text = 'Natural Frequency';
            app.NaturalFrequencyEditField = uieditfield(app.HarmonicFilterPanel, 'numeric');
            app.NaturalFrequencyEditField.Value = 60;
            app.ActiveCheckBox = uicheckbox(app.HarmonicFilterPanel);
            app.ActiveCheckBox.Text = 'Active';
            app.ActiveCheckBox.Value = true;
            app.QualityEditFieldLabel = uilabel(app.HarmonicFilterPanel);
            app.QualityEditFieldLabel.Text = 'Quality';
            app.QualityEditField = uieditfield(app.HarmonicFilterPanel, 'numeric');
            app.QualityEditField.Value = 80;
            app.AttenuationdBEditFieldLabel = uilabel(app.HarmonicFilterPanel);
            app.AttenuationdBEditFieldLabel.Text = 'Attenuation(dB)';
            app.AttenuationdBEditField = uieditfield(app.HarmonicFilterPanel, 'numeric');
            app.AttenuationdBEditField.Value = 120;
            app.LowpassFilterPanel = uipanel(app.FilteringTab);
            app.LowpassFilterPanel.Title = 'Lowpass Filter';
            app.CutoffFrequencyEditFieldLabel = uilabel(app.LowpassFilterPanel);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.LowpassFilterPanel, 'numeric');
            app.CutoffFrequencyEditField.Value = 350;
            app.ActiveCheckBox_2 = uicheckbox(app.LowpassFilterPanel);
            app.ActiveCheckBox_2.Text = 'Active';
            app.ActiveCheckBox_2.Value = true;
            app.FilterButton = uibutton(app.FilteringTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed2, true);
            app.FilterButton.Text = 'Filter';
            app.GeneratePeriodogramButton = uibutton(app.FilteringTab, 'push');
            app.GeneratePeriodogramButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePeriodogramButtonPushed, true);
            app.GeneratePeriodogramButton.Text = 'Generate Periodogram';
            app.HighpassFilterPanel = uipanel(app.FilteringTab);
            app.HighpassFilterPanel.Title = 'Highpass Filter';
            app.CutoffFrequencyEditField_2Label = uilabel(app.HighpassFilterPanel);
            app.CutoffFrequencyEditField_2Label.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField_2 = uieditfield(app.HighpassFilterPanel, 'numeric');
            app.CutoffFrequencyEditField_2.Value = 0.5;
            app.ActiveCheckBox_3 = uicheckbox(app.HighpassFilterPanel);
            app.ActiveCheckBox_3.Text = 'Active';
            app.ActiveCheckBox_3.Value = true;
            app.SavitzkyGolayFilteringPanel = uipanel(app.FilteringTab);
            app.SavitzkyGolayFilteringPanel.Title = 'Savitzky-Golay Filtering';
            app.OrderEditField_2Label = uilabel(app.SavitzkyGolayFilteringPanel);
            app.OrderEditField_2Label.Text = 'Order';
            app.OrderEditField_2 = uieditfield(app.SavitzkyGolayFilteringPanel, 'numeric');
            app.OrderEditField_2.Value = 2;
            app.FrameLengthoddEditFieldLabel = uilabel(app.SavitzkyGolayFilteringPanel);
            app.FrameLengthoddEditFieldLabel.Text = 'Frame Length (odd)';
            app.FrameLengthoddEditField = uieditfield(app.SavitzkyGolayFilteringPanel, 'numeric');
            app.FrameLengthoddEditField.Value = 100;
            app.ActiveCheckBox_4 = uicheckbox(app.SavitzkyGolayFilteringPanel);
            app.ActiveCheckBox_4.Text = 'Active';
            app.FFTAnalysisButton = uibutton(app.FilteringTab, 'push');
            app.FFTAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @FFTAnalysisButtonPushed, true);
            app.FFTAnalysisButton.Text = 'FFT Analysis';
            app.PlotMultipleTab = uitab(app.TabGroup);
            app.PlotMultipleTab.Title = 'Plot Multiple';
            app.TabGroup4 = uitabgroup(app.PlotMultipleTab);
            app.MainTab_2 = uitab(app.TabGroup4);
            app.MainTab_2.Title = 'Main';
            app.TextArea_4 = uitextarea(app.MainTab_2);
            app.TextArea_4.Value = {'Enter Channels to plot.'; 'Ex.: To export channels 1 to 5 and channel 15 enter:'; '1:5 15 '};
            app.PlotButton = uibutton(app.MainTab_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ChannelsEditField_2Label = uilabel(app.MainTab_2);
            app.ChannelsEditField_2Label.Text = 'Channels';
            app.ChannelsToPlot = uieditfield(app.MainTab_2, 'text');
            app.ChannelsToPlot.Value = '5:10';
            app.StyleButtonGroup = uibuttongroup(app.MainTab_2);
            app.StyleButtonGroup.Title = 'Style';
            app.FlowButton = uiradiobutton(app.StyleButtonGroup);
            app.FlowButton.Text = 'Flow';
            app.FlowButton.Value = true;
            app.VerticalButton = uiradiobutton(app.StyleButtonGroup);
            app.VerticalButton.Text = 'Vertical';
            app.HorizontalButton = uiradiobutton(app.StyleButtonGroup);
            app.HorizontalButton.Text = 'Horizontal';
            app.xnButton = uiradiobutton(app.StyleButtonGroup);
            app.xnButton.Text = '2 x n';
            app.xnButton_2 = uiradiobutton(app.StyleButtonGroup);
            app.xnButton_2.Text = '3 x n';
            app.nx3Button = uiradiobutton(app.StyleButtonGroup);
            app.nx3Button.Text = 'n x 3';
            app.nx2Button = uiradiobutton(app.StyleButtonGroup);
            app.nx2Button.Text = 'n x 2';
            app.FilterCheckBox = uicheckbox(app.MainTab_2);
            app.FilterCheckBox.Text = 'Filter';
            app.LimitsTab_2 = uitab(app.TabGroup4);
            app.LimitsTab_2.Title = 'Limits';
            app.ApplyLimitsButton_2 = uibutton(app.LimitsTab_2, 'push');
            app.ApplyLimitsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyLimitsButton_2Pushed, true);
            app.ApplyLimitsButton_2.Text = 'Apply Limits';
            app.XLimitLowerEditField_2Label = uilabel(app.LimitsTab_2);
            app.XLimitLowerEditField_2Label.Text = 'X Limit Lower';
            app.XLimitLowerEditField_2 = uieditfield(app.LimitsTab_2, 'numeric');
            app.YLimitLowerEditField_2Label = uilabel(app.LimitsTab_2);
            app.YLimitLowerEditField_2Label.Text = 'Y Limit Lower';
            app.YLimitLowerEditField_2 = uieditfield(app.LimitsTab_2, 'numeric');
            app.XLimitHigherEditField_2Label = uilabel(app.LimitsTab_2);
            app.XLimitHigherEditField_2Label.Text = 'X Limit Higher';
            app.XLimitHigherEditField_2 = uieditfield(app.LimitsTab_2, 'numeric');
            app.XLimitHigherEditField_2.Value = 1;
            app.YLimitHigherEditField_2Label = uilabel(app.LimitsTab_2);
            app.YLimitHigherEditField_2Label.Text = 'Y Limit Higher';
            app.YLimitHigherEditField_2 = uieditfield(app.LimitsTab_2, 'numeric');
            app.YLimitHigherEditField_2.Value = 1;
            app.MathTab = uitab(app.TabGroup);
            app.MathTab.Title = 'Math';
            app.Channel1EditFieldLabel = uilabel(app.MathTab);
            app.Channel1EditFieldLabel.Text = 'Channel 1';
            app.Channel1EditField = uieditfield(app.MathTab, 'numeric');
            app.Channel1EditField.Value = 9;
            app.Channel2EditFieldLabel = uilabel(app.MathTab);
            app.Channel2EditFieldLabel.Text = 'Channel 2';
            app.Channel2EditField = uieditfield(app.MathTab, 'numeric');
            app.Channel2EditField.Value = 10;
            app.OperationButtonGroup = uibuttongroup(app.MathTab);
            app.OperationButtonGroup.Title = 'Operation';
            app.Subtraction12Button = uiradiobutton(app.OperationButtonGroup);
            app.Subtraction12Button.Text = 'Subtraction (1 -2)';
            app.Subtraction12Button.Value = true;
            app.Derivative1Button = uiradiobutton(app.OperationButtonGroup);
            app.Derivative1Button.Text = 'Derivative (1)';
            app.Integral1Button = uiradiobutton(app.OperationButtonGroup);
            app.Integral1Button.Text = 'Integral (1)';
            app.CalculateButton = uibutton(app.MathTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.TabGroup3 = uitabgroup(app.ExportTab);
            app.MainTab = uitab(app.TabGroup3);
            app.MainTab.Title = 'Main';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter Channels to export.'; 'Ex.: To export channels 1 to 5 and channel 15 enter:'; '1:5 15 '; 'Large files may take some time.'; ''; 'Add information to the header on the Additional Info Tab.'; ''; 'Help on how to read the file on the Help Tab.'};
            app.FilteredButtonGroup = uibuttongroup(app.MainTab);
            app.FilteredButtonGroup.Title = 'Filtered?';
            app.RawDataButton = uiradiobutton(app.FilteredButtonGroup);
            app.RawDataButton.Text = 'Raw Data';
            app.RawDataButton.Value = true;
            app.FilteredDataButton = uiradiobutton(app.FilteredButtonGroup);
            app.FilteredDataButton.Text = 'Filtered Data';
            app.ExportButton = uibutton(app.MainTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ChannelsEditFieldLabel = uilabel(app.MainTab);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.MainTab, 'text');
            app.ChannelsEditField.Value = '5:10';
            app.FileNameEditField_2Label = uilabel(app.MainTab);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.MainTab, 'text');
            app.SelectDirectoryButton = uibutton(app.MainTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.AdditionalInfoTab = uitab(app.TabGroup3);
            app.AdditionalInfoTab.Title = 'Additional Info';
            app.DateEditFieldLabel = uilabel(app.AdditionalInfoTab);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.AdditionalInfoTab, 'text');
            app.DateEditField.Placeholder = 'dd/mm/yyyy';
            app.RecordingEditFieldLabel = uilabel(app.AdditionalInfoTab);
            app.RecordingEditFieldLabel.Text = 'Recording';
            app.RecordingEditField = uieditfield(app.AdditionalInfoTab, 'text');
            app.RecordingEditField.Placeholder = 'Ex.: Recording 1';
            app.OBSTextAreaLabel = uilabel(app.AdditionalInfoTab);
            app.OBSTextAreaLabel.Text = 'OBS';
            app.OBSTextArea = uitextarea(app.AdditionalInfoTab);
            app.OBSTextArea.Placeholder = 'Ex.: Sinus Rythm.';
            app.AnimalEditFieldLabel = uilabel(app.AdditionalInfoTab);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalEditField = uieditfield(app.AdditionalInfoTab, 'text');
            app.AnimalEditField.Placeholder = 'Ex.: Rabbit';
            app.MassEditFieldLabel = uilabel(app.AdditionalInfoTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.AdditionalInfoTab, 'text');
            app.MassEditField.Placeholder = 'Ex.: 3.450 Kg';
            app.HelpTab_2 = uitab(app.TabGroup3);
            app.HelpTab_2.Title = 'Help';
            app.HowtoReadExportedDataTextAreaLabel = uilabel(app.HelpTab_2);
            app.HowtoReadExportedDataTextAreaLabel.Text = 'How to Read Exported Data';
            app.HowtoReadExportedDataTextArea = uitextarea(app.HelpTab_2);
            app.HowtoReadExportedDataTextArea.Editable = 'off';
            app.HowtoReadExportedDataTextArea.Value = {'The exported data is saved on a struct with fields: Header, Data, Timestamps, TTL.'; 'Ex.: a file exported named ''Ex.mat''; To load the data use the command:'; ''; 'D = load(''Ex.mat''); '; 'D.Header % Contain information about the data, such as channels exported, date, and others.'; 'D.Timestamps % Contains the timestamps'; 'D.TTL % Contain Timestamps with the time of the rising edge of the TTL signal.'; 'D.Data % Contains the data from the channels, but is not indexed by the channels numbers, Ex.: If the channels 5 to 10 were exported the D.Data would be made from an array with indexes from 1 to 6, so if the channel 6 data is wated you need to use the command:'; ''; 'D.Data(2, :);'; ''; 'Alternatively, if the specific order is not known use.'; ''; 'D.Data(find(D.Header.Channels == 6), :); % will return data from channel 6, if the was not exported an error will return.'; ''};
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.MEAElectrodeDispositionButton = uibutton(app.HelpTab, 'push');
            app.MEAElectrodeDispositionButton.ButtonPushedFcn = createCallbackFcn(app, @MEAElectrodeDispositionButtonPushed, true);
            app.MEAElectrodeDispositionButton.Text = 'MEA Electrode Disposition';
            app.TextArea_3 = uitextarea(app.HelpTab);
            app.TextArea_3.Value = {'In these images the MEAs are viewed from the front, i.e., the wires are coming from behind the image. '; 'In photos of the heart the electrodes numbers needs to be mirrored.'};
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldonCheckBoxValueChanged, true);
            app.HoldonCheckBox.Text = 'Hold on';
            app.OpenExternalButton = uibutton(app.UIFigure, 'push');
            app.OpenExternalButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExternalButtonPushed, true);
            app.OpenExternalButton.Text = 'Open External';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Open_Ephys

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end